classdef Ugrit_cassifi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DetailLabel                   matlab.ui.control.Label
        DetailTextArea                matlab.ui.control.TextArea
        Labelname_th                  matlab.ui.control.Label
        showThainameCheckBox          matlab.ui.control.CheckBox
        showAccuracyCheckBox          matlab.ui.control.CheckBox
        ThaifoodnamewithpictureLabel  matlab.ui.control.Label
        Labelpercen                   matlab.ui.control.Label
        errorLabel                    matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        INPUTButton                   matlab.ui.control.Button
        Labelname                     matlab.ui.control.Label
    end


    properties (Access = private)
        im
        YPred
        probs
        Load1
        imgname_eng
        imgname_th
        AccuracyP
        AccuracyP_int
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Load1 = load('data/thfood_dataset.mat');
        end

        % Button pushed function: INPUTButton
        function INPUTButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.jpg'; '*.png'}, 'Select a file');
           if filename ~= 0
                filename=strcat(pathname,filename);
                [~,~,ext] = fileparts(filename);
                if ismember((ext), {'.jpg', '.png'})
                    app.im = imread(filename);
                    app.im = imresize(app.im,[224,224]);
                    app.Image.Visible="on";
                    app.Image.ImageSource = filename;
                    [app.YPred,app.probs] = classify(app.Load1.trainedNetwork_1,app.im);
                    app.errorLabel.Visible = "off";
                    switch app.YPred
                        case 'Green_Curry'
                            app.imgname_th = 'แกงเขียวหวาน';
                            app.imgname_eng = 'Green Curry';
                            app.DetailTextArea.Value = '        Green curry, a Thai culinary gem, is characterized by its vivid green color derived from a blend of fresh green chilies, coconut milk, and fragrant herbs. Offering a delightful mix of sweet, savory, and spicy flavors, this versatile dish can feature proteins like chicken or shrimp. Typically served with rice or noodles, green curry is a beloved and iconic Thai curry celebrated for its rich taste and aromatic profile.';
                        case 'Khao_phat'
                            app.imgname_th = 'ข้าวผัด';
                            app.imgname_eng = 'Khaophat';
                            app.DetailTextArea.Value = '        Khaophat, a popular Thai fried rice dish, features fragrant jasmine rice stir-fried with a medley of ingredients like vegetables, meat (chicken, shrimp, or pork), and aromatic seasonings such as soy sauce and fish sauce. Often topped with a sprinkle of green onions, Khaophat offers a delightful combination of textures and savory flavors in every bite.';
                        case 'Khao_soi'
                            app.imgname_th = 'ข้าวซอย';
                            app.imgname_eng = 'Khaosoi';
                            app.DetailTextArea.Value = '        Khao Soi, a northern Thai noodle soup, combines a rich coconut curry broth with egg noodles and a choice of protein (chicken, beef, or pork). Garnished with crispy fried noodles, shallots, lime, and pickled mustard greens, this dish offers a harmonious blend of creamy, spicy, and tangy flavors, making it a Thai culinary favorite.';
                        case 'Massaman_Curry'
                            app.imgname_th = 'แกงมัสมั่น';
                            app.imgname_eng = 'Massaman Curry';
                            app.DetailTextArea.Value = '        Massaman Curry, a Thai culinary gem, is known for its rich and complex flavors. This aromatic curry features a blend of spices, coconut milk, and tender meat (often beef or chicken). Potatoes, peanuts, and onions add texture, creating a savory and slightly sweet dish. Massaman Curry offers a delightful taste journey, balancing sweetness and spiciness.';
                        case 'Pad_Thai'
                            app.imgname_th = 'ผัดไทย';
                            app.imgname_eng = 'Pad Thai';
                            app.DetailTextArea.Value = '        Pad Thai, a quintessential Thai street food, is a stir-fried noodle dish celebrated for its sweet, sour, and savory flavors. Combining flat rice noodles with tofu or shrimp, bean sprouts, peanuts, and lime, it is commonly seasoned with tamarind paste and fish sauce. This iconic dish represents the vibrant and diverse tastes of Thai cuisine, appealing to a wide range of palates.';
                        case 'Phanaeng_Curry'
                            app.imgname_th = 'แกงพะแนง';
                            app.imgname_eng = 'Phanaeng Curry';
                            app.DetailTextArea.Value = '        Phanaeng Curry, a Thai curry variant, is renowned for its luscious and creamy texture. This flavorful dish features a red curry paste base, combined with coconut milk, creating a velvety sauce. Typically prepared with chicken or beef, it boasts a mild sweetness and a hint of spice. Phanaeng Curry is a delightful blend of aromatic herbs and spices, making it a Thai culinary favorite.';
                        case 'Phat_kaphrao'
                            app.imgname_th = 'ผัดกะเพรา';
                            app.imgname_eng = 'Pad kaphrao';
                            app.DetailTextArea.Value = '        Pad Krapao, a beloved Thai stir-fry, is known for its bold and aromatic flavors. Typically made with minced meat (commonly pork, chicken, or beef), fresh Thai basil, and chili, it is seasoned with garlic, soy sauce, and oyster sauce. Served over rice and often topped with a fried egg, Pad Krapao offers a delightful combination of spicy, savory, and herbal notes.';
                        case 'Roti_canai'
                            app.imgname_th = 'โรตี';
                            app.imgname_eng = 'Roti';
                            app.DetailTextArea.Value = '        Roti, a popular Thai street food, consists of thin, unleavened flatbread typically served with various accompaniments. Whether sweet or savory, roti variations include Roti Canai with curry, or Roti Sai Mai filled with sweet strands of cotton candy. This versatile treat showcases the influence of Indian cuisine on Thai street food, offering a delicious fusion of flavors.';
                        case 'Tom_kha_gai'
                            app.imgname_th = 'ต้มข่าไก่';
                            app.imgname_eng = 'Tom kha gai';
                            app.DetailTextArea.Value = '        Tom Kha Gai, a classic Thai soup, features a fragrant and creamy coconut milk broth infused with galangal, lemongrass, and kaffir lime leaves. This soup is adorned with tender chicken, mushrooms, and a hint of Thai chili. The result is a harmonious blend of sweet, sour, and spicy flavors, creating a comforting and aromatic dish that exemplifies Thai cuisine.';
                        case 'Tom_yum'
                            app.imgname_th = 'ต้มยำ';
                            app.imgname_eng = 'Tomyum';
                            app.DetailTextArea.Value = '        Tom Yum, a quintessential Thai soup, is renowned for its bold and zesty flavors. This hot and sour soup features a fragrant broth infused with lemongrass, galangal, and kaffir lime leaves. Shrimp, mushrooms, and chili add depth and spice, creating a delightful combination of tangy and savory tastes. Tom Yum is a beloved and iconic Thai dish.';
                    end

                    if app.AccuracyP_int >= 90
                        app.Labelname.Text=string(("Name : ")+app.imgname_eng);
                        app.Labelname_th.Text=string(("Thainame : ")+app.imgname_th);                   
                    else
                        app.Labelname.Text=string(("Name : Maybe is ")+app.imgname_eng);
                        app.Labelname_th.Text=string(("Thai name : ")+app.imgname_th);
                    end

                    app.AccuracyP = num2str(100 * max(app.probs), '%.1f');
                    app.AccuracyP_int = 100 * max(app.probs);
                    app.Labelpercen.Text=("Accuracy : ")+round(app.AccuracyP_int)+(" %");
                    app.DetailTextArea.Visible="on";
                    app.Labelname.Visible="on";
                    app.DetailLabel.Visible="on";

                else
                    app.errorLabel.Visible="on";
                    app.Labelname.Visible="off";
                    app.DetailTextArea.Visible="off";
                    app.DetailLabel.Visible="off";
                    app.Labelname_th.Visible="off";
                    app.Labelpercen.Visible = "off";
                    app.errorLabel.Text = 'ERROR : Wrong Type of file.';
                end

            end
        end

        % Value changed function: showThainameCheckBox
        function showThainameCheckBoxValueChanged(app, event)
            if app.showThainameCheckBox.Value == true
                app.Labelname_th.Visible = 'on';
            end
            if app.showThainameCheckBox.Value == false
                app.Labelname_th.Visible = 'off';
            end
        end

        % Value changed function: showAccuracyCheckBox
        function showAccuracyCheckBoxValueChanged(app, event)
            if app.showAccuracyCheckBox.Value == true
                app.Labelpercen.Visible='on';
            end
            if app.showAccuracyCheckBox.Value == false
                app.Labelpercen.Visible='off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Labelname = uilabel(app.UIFigure);
            app.Labelname.Visible = 'off';
            app.Labelname.Text = '';
            app.INPUTButton = uibutton(app.UIFigure, 'push');
            app.INPUTButton.ButtonPushedFcn = createCallbackFcn(app, @INPUTButtonPushed, true);
            app.INPUTButton.Text = 'INPUT';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.errorLabel = uilabel(app.UIFigure);
            app.errorLabel.Visible = 'off';
            app.errorLabel.Text = 'Labelerror';
            app.Labelpercen = uilabel(app.UIFigure);
            app.Labelpercen.Visible = 'off';
            app.Labelpercen.Text = '';
            app.ThaifoodnamewithpictureLabel = uilabel(app.UIFigure);
            app.ThaifoodnamewithpictureLabel.Text = 'Thai food name with picture';
            app.showAccuracyCheckBox = uicheckbox(app.UIFigure);
            app.showAccuracyCheckBox.ValueChangedFcn = createCallbackFcn(app, @showAccuracyCheckBoxValueChanged, true);
            app.showAccuracyCheckBox.Text = 'show Accuracy';
            app.showThainameCheckBox = uicheckbox(app.UIFigure);
            app.showThainameCheckBox.ValueChangedFcn = createCallbackFcn(app, @showThainameCheckBoxValueChanged, true);
            app.showThainameCheckBox.Text = 'show Thai name';
            app.Labelname_th = uilabel(app.UIFigure);
            app.Labelname_th.Visible = 'off';
            app.Labelname_th.Text = '';
            app.DetailTextArea = uitextarea(app.UIFigure);
            app.DetailTextArea.Editable = 'off';
            app.DetailTextArea.Visible = 'off';
            app.DetailLabel = uilabel(app.UIFigure);
            app.DetailLabel.Visible = 'off';
            app.DetailLabel.Text = 'Detail :';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ugrit_cassifi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end